classdef ControlUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        StageAdjustmentPanel         matlab.ui.container.Panel
        LeftButton                   matlab.ui.control.Button
        RightButton                  matlab.ui.control.Button
        DownButton                   matlab.ui.control.Button
        UpButton                     matlab.ui.control.Button
        StepLengthEditFieldLabel     matlab.ui.control.Label
        StepLengthEditField          matlab.ui.control.NumericEditField
        Button_3                     matlab.ui.control.Button
        Button_4                     matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        XPosEditFieldLabel           matlab.ui.control.Label
        XPosEditField                matlab.ui.control.NumericEditField
        YPosEditFieldLabel           matlab.ui.control.Label
        YPosEditField                matlab.ui.control.NumericEditField
        PowerAdjustmentPanel         matlab.ui.container.Panel
        EstPowerGaugeLabel           matlab.ui.control.Label
        EstPowerGauge                matlab.ui.control.LinearGauge
        JEditFieldLabel              matlab.ui.control.Label
        JEditField                   matlab.ui.control.NumericEditField
        MovetoPowerJEditFieldLabel   matlab.ui.control.Label
        MovetoPowerJEditField        matlab.ui.control.NumericEditField
        MoveByStepdegEditFieldLabel  matlab.ui.control.Label
        MoveByStepdegEditField       matlab.ui.control.NumericEditField
        Button                       matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        MovetoAngleEditFieldLabel    matlab.ui.control.Label
        MovetoAngleEditField         matlab.ui.control.NumericEditField
        CurrentPosdegEditFieldLabel  matlab.ui.control.Label
        CurrentPosdegEditField       matlab.ui.control.NumericEditField
        SettingsDebuggingPanel       matlab.ui.container.Panel
        XDeviceEditFieldLabel        matlab.ui.control.Label
        XDeviceEditField             matlab.ui.control.NumericEditField
        YDeviceEditFieldLabel        matlab.ui.control.Label
        YDeviceEditField             matlab.ui.control.NumericEditField
        ZDeviceEditFieldLabel        matlab.ui.control.Label
        ZDeviceEditField             matlab.ui.control.NumericEditField
        XYMotorLampLabel             matlab.ui.control.Label
        XYMotorLamp                  matlab.ui.control.Lamp
        WaveplateLampLabel           matlab.ui.control.Label
        WaveplateLamp                matlab.ui.control.Lamp
        XYHomeButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        PortHandle % Description
        XMotor % Device control handle for XMotor
        YMotor % Device control handle for YMotor
        ZMotor
        COM_Port = 'COM4' % COM Port used
        ThetaMotor %Waveplate motor
        StepConv = 4.9E-1 % 4.9E-1 um/ustep
        ThetaConv = 0.005625; %degrees/ustep;
    end

    methods (Access = private)
    
        function results = MoveByStep(app,int,varargin)
            switch int
                case 0
                    Device = app.XMotor;
                    dir = 1;
                case 1
                    Device = app.XMotor;
                    dir = -1;
                case 2
                    Device = app.YMotor;
                    dir = -1;
                case 3
                    Device = app.YMotor;
                    dir = 1;
                case 4
                    Device = app.ZMotor;
                    dir = 1;
                case 5
                    Device = app.ZMotor;
                    dir = -1;
            end
            if isempty(varagin)
                distance = dir*app.StepLengthEditField.Value*(app.StepConv^(-1));
            else
                distance = varargin{1}*app.StepConv^(-1);
            end
            Device.moverelative(distance);
            app.UpdatePosition();
        end
        
        function results = RotateByStep(app,dir)
            %takes the function and rotates by discretized theta value.
            ThetaCurrent = app.CurrentPosdegEditField.Value; %Current Position in degrees
            StepSize = app.StepLengthEditField.Value;
            NewTheta = mod(ThetaCurrent+StepSize*(dir),360);
            app.Rotate2Pos(NewTheta); %Rotation execution goes to different function (for general use)


        end
        
        function results = InitializeDevices(app,Devices)
            % Initializes individual devices from the Device handle, parsing addresses to functions on the stage setup.
            %for i = 1:length(Devices)
            app.ReassignMotors;
            app.ThetaMotor = Devices(2); 
            app.XYMotorLamp.Color = [0,1,0];
            app.WaveplateLamp.Color = [0,1,0];
            
        end
        
        function results = Rotate2Pos(app,ThetaNew)
            StepVal = ThetaNew/app.ThetaConv; %degrees to steps
            app.ThetaMotor.moveabsolute(StepVal);
            app.ThetaMotor.waitforidle();
            %update theta location this will be the only function that touches this (for anyone who edits this in the future, please only let this function touch this edit field)
            app.CurrentPosdegEditField.Value = ThetaNew;
        end
        
        function results = ReassignMotors(app)
            Devices = app.PortHandle.Devices;
            %kind of just a placeholder until I can figure out unique identifiers
            XNum = app.XDeviceEditField.Value; YNum = app.YDeviceEditField.Value; ZNum = app.ZDeviceEditField.Value;
            app.XMotor = Devices(XNum); app.YMotor = Devices(YNum);
            app.ZMotor = Devices(ZNum);
            app.UpdatePosition();
        end
        
    end

    methods (Access = public)
    
        function results = XYHome(app)
            app.XMotor.home();
            app.YMotor.home();
            app.UpdatePosition();
        end
        
        function results = UpdatePosition(app)
            %assign coordinates to current position
            Conv = app.StepConv*1E-3; %conversion factor, corrected to mm/ustep
            app.XPosEditField.Value = app.XMotor.getposition()*Conv; app.YPosEditField.Value = app.YMotor.getposition()*Conv;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Change app designer text because it's pretty damn inflexible
            
            app.RightButton.Text = char(8594);
            app.LeftButton.Text = char(8592);
            app.UpButton.Text = char(8593);
            app.DownButton.Text = char(8595);
            %Initializing code
            try
                app.PortHandle = ZaberPort(app.COM_Port); %initialize the zaber port object
                Devices = app.PortHandle.Devices;
                app.InitializeDevices(Devices)
                
            catch ME
                f = errordlg('Could not initialize devices');
                waitfor(f);
                app.UIFigureCloseRequest();
            end
            
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.MoveByStep(0);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            app.MoveByStep(1);
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            app.MoveByStep(2);
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            app.MoveByStep(3);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try app.PortHandle.ClosePort();
                
            catch ME
                
            end
            delete(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %+ button
            app.RotateByStep(app.MoveByStepdegEditField.Value*1)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
             %- button
            app.RotateByStep(app.MoveByStepdegEditField.Value*-1)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %+Z button
            app.MoveByStep(4);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %-Z button
            app.MoveByStep(5);
        end

        % Value changed function: XDeviceEditField
        function XDeviceEditFieldValueChanged(app, event)
            app.ReassignMotors();
            
        end

        % Value changed function: YDeviceEditField
        function YDeviceEditFieldValueChanged(app, event)
            app.ReassignMotors();
            
        end

        % Value changed function: ZDeviceEditField
        function ZDeviceEditFieldValueChanged(app, event)
            app.ReassignMotors();
            
        end

        % Value changed function: MovetoAngleEditField
        function MovetoAngleEditFieldValueChanged(app, event)
            value = app.MovetoAngleEditField.Value;
            app.Rotate2Pos(value);
            
        end

        % Button pushed function: XYHomeButton
        function XYHomeButtonPushed(app, event)
            app.XYHome();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StageAdjustmentPanel = uipanel(app.UIFigure);
            app.StageAdjustmentPanel.Title = 'Stage Adjustment';
            app.LeftButton = uibutton(app.StageAdjustmentPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.StageAdjustmentPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.DownButton = uibutton(app.StageAdjustmentPanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.UpButton = uibutton(app.StageAdjustmentPanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.StepLengthEditFieldLabel = uilabel(app.StageAdjustmentPanel);
            app.StepLengthEditFieldLabel.Text = 'Step Length';
            app.StepLengthEditField = uieditfield(app.StageAdjustmentPanel, 'numeric');
            app.StepLengthEditField.Limits = [0 5000];
            app.StepLengthEditField.ValueDisplayFormat = '%.0f';
            app.StepLengthEditField.Value = 1000;
            app.Button_3 = uibutton(app.StageAdjustmentPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '+';
            app.Button_4 = uibutton(app.StageAdjustmentPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '-';
            app.EditField = uieditfield(app.StageAdjustmentPanel, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Focus Toggle';
            app.XPosEditFieldLabel = uilabel(app.StageAdjustmentPanel);
            app.XPosEditFieldLabel.Text = 'X Pos';
            app.XPosEditField = uieditfield(app.StageAdjustmentPanel, 'numeric');
            app.XPosEditField.ValueDisplayFormat = '%.3f';
            app.YPosEditFieldLabel = uilabel(app.StageAdjustmentPanel);
            app.YPosEditFieldLabel.Text = 'Y Pos';
            app.YPosEditField = uieditfield(app.StageAdjustmentPanel, 'numeric');
            app.YPosEditField.ValueDisplayFormat = '%.3f';
            app.PowerAdjustmentPanel = uipanel(app.UIFigure);
            app.PowerAdjustmentPanel.Title = 'Power Adjustment';
            app.EstPowerGaugeLabel = uilabel(app.PowerAdjustmentPanel);
            app.EstPowerGaugeLabel.Text = 'Est. Power';
            app.EstPowerGauge = uigauge(app.PowerAdjustmentPanel, 'linear');
            app.EstPowerGauge.Limits = [0 10];
            app.JEditFieldLabel = uilabel(app.PowerAdjustmentPanel);
            app.JEditFieldLabel.Text = 'J';
            app.JEditField = uieditfield(app.PowerAdjustmentPanel, 'numeric');
            app.JEditField.Editable = 'off';
            app.MovetoPowerJEditFieldLabel = uilabel(app.PowerAdjustmentPanel);
            app.MovetoPowerJEditFieldLabel.Text = 'Move to Power (J)';
            app.MovetoPowerJEditField = uieditfield(app.PowerAdjustmentPanel, 'numeric');
            app.MoveByStepdegEditFieldLabel = uilabel(app.PowerAdjustmentPanel);
            app.MoveByStepdegEditFieldLabel.Text = 'Move By Step (deg)';
            app.MoveByStepdegEditField = uieditfield(app.PowerAdjustmentPanel, 'numeric');
            app.MoveByStepdegEditField.Value = 1;
            app.Button = uibutton(app.PowerAdjustmentPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.PowerAdjustmentPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.MovetoAngleEditFieldLabel = uilabel(app.PowerAdjustmentPanel);
            app.MovetoAngleEditFieldLabel.Text = 'Move to Angle';
            app.MovetoAngleEditField = uieditfield(app.PowerAdjustmentPanel, 'numeric');
            app.MovetoAngleEditField.ValueChangedFcn = createCallbackFcn(app, @MovetoAngleEditFieldValueChanged, true);
            app.MovetoAngleEditField.Value = 50;
            app.CurrentPosdegEditFieldLabel = uilabel(app.PowerAdjustmentPanel);
            app.CurrentPosdegEditFieldLabel.Text = 'Current Pos (deg)';
            app.CurrentPosdegEditField = uieditfield(app.PowerAdjustmentPanel, 'numeric');
            app.CurrentPosdegEditField.Editable = 'off';
            app.CurrentPosdegEditField.Value = 50;
            app.SettingsDebuggingPanel = uipanel(app.UIFigure);
            app.SettingsDebuggingPanel.Title = 'Settings-Debugging';
            app.XDeviceEditFieldLabel = uilabel(app.SettingsDebuggingPanel);
            app.XDeviceEditFieldLabel.Text = 'X Device';
            app.XDeviceEditField = uieditfield(app.SettingsDebuggingPanel, 'numeric');
            app.XDeviceEditField.ValueChangedFcn = createCallbackFcn(app, @XDeviceEditFieldValueChanged, true);
            app.XDeviceEditField.Value = 3;
            app.YDeviceEditFieldLabel = uilabel(app.SettingsDebuggingPanel);
            app.YDeviceEditFieldLabel.Text = 'Y Device';
            app.YDeviceEditField = uieditfield(app.SettingsDebuggingPanel, 'numeric');
            app.YDeviceEditField.ValueChangedFcn = createCallbackFcn(app, @YDeviceEditFieldValueChanged, true);
            app.YDeviceEditField.Value = 4;
            app.ZDeviceEditFieldLabel = uilabel(app.SettingsDebuggingPanel);
            app.ZDeviceEditFieldLabel.Text = 'Z Device';
            app.ZDeviceEditField = uieditfield(app.SettingsDebuggingPanel, 'numeric');
            app.ZDeviceEditField.ValueChangedFcn = createCallbackFcn(app, @ZDeviceEditFieldValueChanged, true);
            app.ZDeviceEditField.Value = 5;
            app.XYMotorLampLabel = uilabel(app.SettingsDebuggingPanel);
            app.XYMotorLampLabel.Text = 'XY Motor';
            app.XYMotorLamp = uilamp(app.SettingsDebuggingPanel);
            app.WaveplateLampLabel = uilabel(app.SettingsDebuggingPanel);
            app.WaveplateLampLabel.Text = 'Waveplate';
            app.WaveplateLamp = uilamp(app.SettingsDebuggingPanel);
            app.XYHomeButton = uibutton(app.SettingsDebuggingPanel, 'push');
            app.XYHomeButton.ButtonPushedFcn = createCallbackFcn(app, @XYHomeButtonPushed, true);
            app.XYHomeButton.Text = 'XY Home';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ControlUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end